classdef trignoHumacSync < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StartAcquisitionButton         matlab.ui.control.Button
        StopAcquisitionButton          matlab.ui.control.Button
        AddDelsysDataButton            matlab.ui.control.Button
        SaveDataButton                 matlab.ui.control.Button
        ParticipantCodeEditFieldLabel  matlab.ui.control.Label
        ParticipantCodeEditField       matlab.ui.control.EditField
        SideEditFieldLabel             matlab.ui.control.Label
        SideEditField                  matlab.ui.control.EditField
        TrialNumberEditFieldLabel      matlab.ui.control.Label
        TrialNumberEditField           matlab.ui.control.EditField
        MVCEditFieldLabel              matlab.ui.control.Label
        MVCEditField                   matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dq = daq("ni"); % initialize daq object
        state = 0;
        data = [];
        EMGdata = [];
    end
    
    methods (Access = private)
        
        function updatePlotArea(app)
            hold(app.UIAxes, 'on')
            
            app.UIAxes.Title.String='Plot of Torque and EMG signal data';
            
            yyaxis(app.UIAxes,'right')
            app.UIAxes.YLabel.String='EMG signal (mV)';
            app.UIAxes.YLim = [0 20];
            app.UIAxes.YColor = [1 0 0];   % font colour
            
            yyaxis(app.UIAxes,'left')
            app.UIAxes.YLabel.String='Torque voltage signal (V)';
            app.UIAxes.YLim = [0 5];   
            
            app.UIAxes.Color = [1 1 0.8];  % plot box colour
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            
        end
        
        function results = plotTorque(app)
            yyaxis(app.UIAxes,'left')
            cla(app.UIAxes);
            app.UIAxes.YLabel.String='Torque (N-m)';
            app.UIAxes.YLim = [0 50];  
            
            plot(app.data.Time, app.data.Dev4_ai0, 'Parent', app.UIAxes);
            xlabel("Time (s)", 'Parent', app.UIAxes);
        end
        
        function results = plotEMG(app)
            yyaxis(app.UIAxes,'right')
            gain = 2000;
            offset = 5;
            colors = ['b-', 'r-'];
            for i=1:size(app.EMGdata,2)-1
                plot(seconds(table2array(app.EMGdata(:,1))), (offset*i)+(table2array(app.EMGdata(:, i+1))*gain), colors(i), 'Parent', app.UIAxes);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StopAcquisitionButton.Enable = 'off';
            app.AddDelsysDataButton.Enable = 'off';

            hold(app.UIAxes, 'on')
            
            app.UIAxes.Title.String='Plot of Torque and EMG signal data';
            
            yyaxis(app.UIAxes,'right')
            app.UIAxes.YLabel.String='EMG signal (mV)';
            app.UIAxes.YLim = [0 20];
            app.UIAxes.YColor = [1 0 0];   % font colour
            
            yyaxis(app.UIAxes,'left')
            app.UIAxes.YLabel.String='Torque voltage signal (V)';
            app.UIAxes.YLim = [0 2];   
            
            app.UIAxes.Color = [1 1 0.8];  % plot box colour
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
        end

        % Button pushed function: StartAcquisitionButton
        function StartAcquisitionButtonPushed(app, event)
            
            app.StartAcquisitionButton.Enable = 'off'; % prevents error from continuous pressing of button
            app.StopAcquisitionButton.Enable = 'on'; 
            app.AddDelsysDataButton.Enable = 'off';
            
            app.state = 1;
            app.dq.Rate = 100;
            
            trigger(app.dq, app.state);
            ch1 = addinput(app.dq,"Dev4",0:1,"Voltage");
            ch1(1).TerminalConfig = "SingleEnded";
            ch1(2).TerminalConfig = "SingleEnded";
            
            app.data = [];
            legend(app.UIAxes, 'hide');
            start(app.dq, "Continuous")

            cla(app.UIAxes, 'reset')
            updatePlotArea(app);

            
            while app.state==1 % Increase or decrease the pause duration to fit your needs.
                scanData = read(app.dq, "all");
                app.data = [app.data; scanData];                
                
                % Uncomment the following lines to enable live plotting.
                yyaxis(app.UIAxes,'left')
                plot(app.data.Time, app.data.Variables, 'Parent', app.UIAxes)
                pause(0.1);
                
            end
            
            stop(app.dq);
            flush(app.dq);
            removechannel(app.dq,1:2);
            pause(0.5)
            trigger(app.dq, app.state);
            clear ch1;

        end

        % Button pushed function: StopAcquisitionButton
        function StopAcquisitionButtonPushed(app, event)
            app.StopAcquisitionButton.Enable = 'off'; 
            app.state = 0;
            
            app.data.Dev4_ai0 = app.data.Dev4_ai0*32768*1.3558/500; % convert to N-m (approx.)
            
            % Plot torque data with N-m units
            plotTorque(app);

%             
            pause(3); % delay prevents errors due to pushing start acquisition too soon.

            app.StartAcquisitionButton.Enable = 'on';
            app.AddDelsysDataButton.Enable = 'on';
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            clear app.dq;
            delete(app)
            
        end

        % Button pushed function: AddDelsysDataButton
        function AddDelsysDataButtonPushed(app, event)
            data = [];
            [filename, filepath] = uigetfile('*.hpf');
            dos(['"C:\Program Files (x86)\Delsys, Inc\Delsys File Utility\DelsysFileUtil.exe" -nogui -o CSV -i ' strcat(filepath, filename)]);
            data = readtable(strcat(filepath, filename(1:end-4), '.csv'));
            data = data(~any(ismissing(data),2),:); % remove NaN values
            
            numChannels = size(data,2)/2;
            data = removevars(data, 3:2:numChannels*2);
            data.Properties.VariableNames(1) = {'time_s'};
            
            data.time_s = data.time_s - 1.39;  %this accounts for time delay
            
            app.EMGdata = data;
            
            plotTorque(app);
            plotEMG(app);
            legend(app.UIAxes, {'TORQUE', app.EMGdata.Properties.VariableNames{2}(1:end-5),app.EMGdata.Properties.VariableNames{3}(1:end-5)});
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            humacData = timetable2table(app.data);
            humacData.Time = seconds(humacData.Time);
            
            timestamp = datestr(now, 'yyyymmmddTHHMMSS');
            filename = strcat(app.ParticipantCodeEditField.Value,'_',app.SideEditField.Value,'_',app.MVCEditField.Value,'_',app.TrialNumberEditField.Value,'_', timestamp, '_');
            
            
            writetable(app.EMGdata, filename+"trigno.csv");
            writetable(humacData, filename+"humac.csv");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartAcquisitionButton = uibutton(app.UIFigure, 'push');
            app.StartAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @StartAcquisitionButtonPushed, true);
            app.StartAcquisitionButton.Text = 'Start Acquisition';
            app.StopAcquisitionButton = uibutton(app.UIFigure, 'push');
            app.StopAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @StopAcquisitionButtonPushed, true);
            app.StopAcquisitionButton.Text = 'Stop Acquisition';
            app.AddDelsysDataButton = uibutton(app.UIFigure, 'push');
            app.AddDelsysDataButton.ButtonPushedFcn = createCallbackFcn(app, @AddDelsysDataButtonPushed, true);
            app.AddDelsysDataButton.Text = 'Add Delsys Data';
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.ParticipantCodeEditFieldLabel = uilabel(app.UIFigure);
            app.ParticipantCodeEditFieldLabel.Text = 'Participant Code';
            app.ParticipantCodeEditField = uieditfield(app.UIFigure, 'text');
            app.ParticipantCodeEditField.Value = 'P001';
            app.SideEditFieldLabel = uilabel(app.UIFigure);
            app.SideEditFieldLabel.Text = 'Side';
            app.SideEditField = uieditfield(app.UIFigure, 'text');
            app.SideEditField.Value = 'left';
            app.TrialNumberEditFieldLabel = uilabel(app.UIFigure);
            app.TrialNumberEditFieldLabel.Text = 'Trial Number';
            app.TrialNumberEditField = uieditfield(app.UIFigure, 'text');
            app.TrialNumberEditField.Value = '1';
            app.MVCEditFieldLabel = uilabel(app.UIFigure);
            app.MVCEditFieldLabel.Text = '% MVC';
            app.MVCEditField = uieditfield(app.UIFigure, 'text');
            app.MVCEditField.Value = '100';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trignoHumacSync

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end